classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UI                           matlab.ui.Figure
        Image_map                    matlab.ui.control.Image
        Image_cam_arm                matlab.ui.control.Image
        ArmCameraImageLabel          matlab.ui.control.Label
        MapViewGoalSelectionLabel    matlab.ui.control.Label
        DetectedObjectsListBoxLabel  matlab.ui.control.Label
        DetectedObjectsListBox       matlab.ui.control.ListBox
        ArmDefaultPositionButton     matlab.ui.control.Button
        KinectCameraImageLabel       matlab.ui.control.Label
        Image_cam_kinect             matlab.ui.control.Image
        BringRobottoUserButton       matlab.ui.control.Button
        SendRobottoFridgeButton      matlab.ui.control.Button
        MoveHighlightedObjectfromtoSideButton  matlab.ui.control.Button
        GrabHighlightedObjectButton  matlab.ui.control.Button
        SendRobottoMapSelectedGoalButton  matlab.ui.control.Button
        DetectFoodButton             matlab.ui.control.Button
        DeliverObjectButton          matlab.ui.control.Button
        ForwardButton                matlab.ui.control.Button
        BackwardButton               matlab.ui.control.Button
        RightButton                  matlab.ui.control.Button
        LeftButton                   matlab.ui.control.Button
        CCWButton                    matlab.ui.control.Button
        CWButton                     matlab.ui.control.Button
        STOPButton                   matlab.ui.control.Button
    end

    
    properties (Access = public)
        joy % Description
        joy_data % Description
        squared_map_img % Description
        pix_per_meter % Description
        rows % Description
        columns % Description
        rows_added % Description
        columns_added % Description
        angle % Description
        cam_img_topic_arm % Description
        cam_img_data_arm % Description
        cam_img_topic_kinect % Description
        cam_img_data_kinect
        pub_goal_mode % Description
        msg_goal_mode % Description
        rcnn
        robotArm
        peripherals
        base
        objects
        home_position   % for detection to check whether it is in the home position
        home_orientation
        tag_pos
        tag_ori
        detected_objects
        object_list
    end
   
    methods (Access = public)
        
        function square_map_1m(app,size_map)
            I = imread(app.Image_map.ImageSource);
            I = imresize(I,2);
            [app.rows, app.columns, num_ch] = size(I);
            if num_ch == 3
                I = rgb2gray(I);
            end
            
            app.pix_per_meter = round(app.rows/size_map(1)); 
            for row = 1 : app.pix_per_meter : app.rows
              I(row, :) = 255;
            end
            for col = 1 : app.pix_per_meter : app.columns
              I(:, col) = 255;
            end
            app.squared_map_img = repmat(I, 1, 1, 3);
            app.Image_map.ImageSource = app.squared_map_img;
        end
        
        function ros_connector(app)
            rosshutdown;
            rosinit('192.168.1.103');
            % Set Publishers
            app.pub_goal_mode = rospublisher('/goal_mode','std_msgs/String');
            app.msg_goal_mode = rosmessage(app.pub_goal_mode);
            
            ros_joystick_init(app);
            ros_camera_init(app);
            ros_kinect_init(app);
            ros_detection_init(app);
            % Start listening  
            ros_listen(app);
            
        end
        
        function ros_joystick_init(app)
            % app.joy = rossubscriber('/joy');
        end
        
        function ros_camera_init(app)
            % app.cam_img_topic_arm = rossubscriber('/usb_cam/image_raw/compressed');
            app.cam_img_topic_arm = rossubscriber('/camera/color/image_raw/compressed');
        end
        
        function ros_kinect_init(app)
            app.cam_img_topic_kinect = rossubscriber('/kin1/sd/image_color_rect/compressed');
            % app.cam_img_topic_kinect = rossubscriber('/fiducial_images/compressed');
        end
        
        function ros_detection_init(app)
            addpath('./RobotRaconteur-0.9.0-MATLAB-glnxa64/');
            app.peripherals = RobotRaconteur.Connect('tcp://192.168.1.108:1234/KinovaPeripheralsServer/peripherals');
            app.robotArm = RobotRaconteur.Connect('tcp://192.168.1.108:4567/KinovaJointServer/Kinova');
            app.base = RobotRaconteur.Connect('tcp://192.168.1.108:5416/BaseServer/Base');
            load('model/rcnn_3objects.mat' );
            app.rcnn = rcnn_food_water;
            app.objects = ["water", "cola"];
            
            app.detected_objects = [];
            app.object_list = [];
            
            app.home_position = [0.2127; -0.2569; 0.5074];
            app.home_orientation = [0.6482; 0.3191; 0.4297; 0.5416];
            app.tag_pos = [];
            app.tag_ori = [];
        end
        
        function ros_listen(app)
            %app.joy_data = receive(app.joy, 10);
            % while (app.joy_data.Buttons(9) ~= 1) % Back button on joystick
            while (1) % Back button on joystick
    
                % Update 
                update_robot_pos_in_map(app);
                % pause(0.1);
                
                % Update Arm Camera Image
                try
                    app.cam_img_data_arm = receive(app.cam_img_topic_arm, 10);
                    app.Image_cam_arm.ImageSource = readImage(app.cam_img_data_arm);
                catch 
                    disp('No realsense image')
                end
                
                % pause(0.1);
                
                % Update Kinect Camera Image
                try
                    app.cam_img_data_kinect = receive(app.cam_img_topic_kinect, 10);
                    app.Image_cam_kinect.ImageSource = readImage(app.cam_img_data_kinect);
                catch 
                    disp('No kinect image')
                end
                
                % pause(0.1);
                
                % app.joy_data = receive(app.joy, 10);
            end
        end
    
        function update_image(app)
            pause(1)
            try
                app.cam_img_data_arm = receive(app.cam_img_topic_arm, 10);
                app.Image_cam_arm.ImageSource = readImage(app.cam_img_data_arm);
                draw_bounding_box(app);
            catch 
                disp('No realsense image')
            end
            
            % pause(0.1);
            
            % Update Kinect Camera Image
            try
                app.cam_img_data_kinect = receive(app.cam_img_topic_kinect, 10);
                app.Image_cam_kinect.ImageSource = readImage(app.cam_img_data_kinect);
            catch 
                disp('No kinect image')
            end
        end
        
        function update_robot_pos_in_map(app)
%             precision = round(app.pix_per_meter/10);
%             if -app.joy_data.Axes(1) > 0
%                 app.columns_added = app.columns_added + precision;
%             elseif -app.joy_data.Axes(1) < 0
%                 app.columns_added = app.columns_added - precision;
%             end
%             % bound the columns in the image range
%             app.columns_added = bound(app, app.columns_added, -round(app.columns/2), round(app.columns/2)); 
%             
%             if -app.joy_data.Axes(2) > 0
%                 app.rows_added = app.rows_added + precision;
%             elseif -app.joy_data.Axes(2) < 0
%                 app.rows_added = app.rows_added - precision;
%             end
%             % bound the rows in the image range
%             app.rows_added = bound(app, app.rows_added, -round(app.rows/2), round(app.rows/2));
%             
%             if app.joy_data.Axes(3) > 0
%                 app.angle = app.angle + precision*2*pi/360;
%             elseif app.joy_data.Axes(3) < 0
%                 app.angle = app.angle - precision*2*pi/360;
%             end
%             % bound the angle btw +pi to -pi
%             app.angle = bound(app, app.angle, -pi, +pi);
%             
%             center_position = [round(app.columns/2)+app.columns_added,round(app.rows/2)+app.rows_added];
%             draw_robot(app, center_position, app.angle)
        end
        
        function draw_robot(app, pos, ang)
            R01 = [1 0 0; 0 -1 0;0 0 1];
            R12 = [cos(ang) -sin(ang) 0; sin(ang) cos(ang) 0; 0 0 1];
            R02 = R01*R12;
            
            w_b = round(app.pix_per_meter*0.53);
            h_b = round(app.pix_per_meter*0.64);
            w_s = round(app.pix_per_meter*0.2);
            h_s = round(app.pix_per_meter*0.08);
            corners = [-w_b/2 -w_b/2  w_b/2  w_b/2 -w_s/2 w_s/2 w_s/2       -w_s/2;...
                        h_b/2 -h_b/2 -h_b/2  h_b/2  h_b/2 h_b/2 (h_b/2+h_s)  (h_b/2+h_s);...
                        0      0      0      0      0     0     0            0];
            corners_in_image = [pos(1);pos(2);0] + R02*corners;
            big_shape = corners_in_image(1:2,1:4);
            sml_shape = corners_in_image(1:2,5:8);
            I_robot_drawn = insertShape(app.squared_map_img,"FilledPolygon",[big_shape(:)'; sml_shape(:)']);
            app.Image_map.ImageSource = I_robot_drawn;
        end
        
        function bounded = bound(app,x,lower,upper)
            bounded = min(max(x,lower),upper);
        end
        
        function objDetection(app,iteration)
            app.object_list = [];
            app.detected_objects = [];
            for j=1:length(app.objects) 
                temp_object.name = app.objects(j);
                temp_object.bbox = [0 0 0 0];
                temp_object.count = 0;
                temp_object.score = 0;
                app.detected_objects = [app.detected_objects, temp_object];
            end
            imgSub = rossubscriber('/camera/color/image_raw');
            imgMsg = receive(imgSub);
            img = readImage(imgMsg);
            pause(1)
            default_pos = app.robotArm.getPos();
            default_ori = app.robotArm.getOri();
            
            locations = [[0,0.05,0]; [0,-0.1,0]];
            for j = 1:length(locations(:,1))
                app.robotArm.cartesian_pose_client(app.robotArm.getPos()+locations(j,:)', app.robotArm.getOri(), 0);
                pause(1)
                for i = 1:iteration
                    [bboxes, scores, label] = detect(app.rcnn, img, 'MiniBatchSize', 32);
                    for j=1:length(app.objects) 
                        index = label==app.objects(j);
                        mask=bboxes > [0 0 80 80];
                        mask = prod(mask, 2);
                        [score, idx] = max(mask .* scores .* index);
        
                        if score >0.7
                            app.detected_objects(j).score = app.detected_objects(j).score + score;
                            app.detected_objects(j).count = app.detected_objects(j).count + 1;
                            app.detected_objects(j).bbox = app.detected_objects(j).bbox + bboxes(idx, : );
                        end
                    end
                end
            end
            app.robotArm.cartesian_pose_client(default_pos, default_ori, 0);
            for j = 1:length(app.detected_objects)
                app.object_list = [app.object_list, app.detected_objects(j).name];
            end
            % result = post_recognition(result);
        end
    
        function move_base(app, dir, distance)
            % our velocity
            velocity = 35/5;
            
            time = distance/velocity;
            % toward the fridge
            %base.moveBase2([-0.3; -0.]);            
            % 5 seconds, 35cm, python velocity = 0.3
            t0 = clock;
            t1 = clock;
            while etime(t1, t0) < time
                app.base.moveBase2(dir);
                t1 = clock;
            end
        end
    
        function rotate_base(app, dir, distance)
            % our velocity
            velocity = 35/5;
            
            time = distance/velocity;
            % toward the fridge
            %base.moveBase2([-0.3; -0.]);            
            % 5 seconds, 35cm, python velocity = 0.3
            t0 = clock;
            t1 = clock;
            while etime(t1, t0) < 2
                disp(1)
                app.base.rotateBase(dir, distance);
                t1 = clock;
            end
        end
    
        function draw_bounding_box(app)
            I_objects = imread(app.Image_cam_arm.ImageSource);
            for j = 1:length(app.detected_objects)
                annotation = sprintf('%s', app.detected_objects(j).name);
                I_objects = insertObjectAnnotation(I_objects, 'rectangle', app.detected_objects(j).bbox, annotation);
            end
            app.Image_cam_arm.ImageSource = I_objects;
        end
        
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.columns_added = 0;
            app.rows_added = 0;
            app.angle = 0;
            square_map_1m(app,[6.8,6]); % [height,width] of the room at img in meters
            ros_connector(app); 
            
        end

        % Button pushed function: ArmDefaultPositionButton
        function ArmDefaultPositionButtonPushed(app, event)
            client = rossvcclient('/j2n6s300_driver/in/home_arm');
            response = call(client);
        end

        % Button pushed function: SendRobottoFridgeButton
        function SendRobottoFridgeButtonPushed(app, event)
            app.msg_goal_mode.Data = '1';
            send(app.pub_goal_mode,app.msg_goal_mode);
%             pre = 'export LD_LIBRARY_PATH="/home/burak/Desktop/AssistiveRobot/catkin_ws_assistiverobot_up_board_arm/devel/lib:/opt/ros/kinetic/lib:/opt/ros/kinetic/lib/x86_64-linux-gnu";';
%             cmd = 'rostopic pub /goal_mode std_msgs/String "data: ''1''" &';
%             [statuss,cmdout] = system([pre cmd]);
        end

        % Button pushed function: BringRobottoUserButton
        function BringRobottoUserButtonPushed(app, event)
            app.msg_goal_mode.Data = '2';
            send(app.pub_goal_mode,app.msg_goal_mode);
%             pre = 'export LD_LIBRARY_PATH="/home/burak/Desktop/AssistiveRobot/catkin_ws_assistiverobot_up_board_arm/devel/lib:/opt/ros/kinetic/lib:/opt/ros/kinetic/lib/x86_64-linux-gnu";';
%             cmd = 'rostopic pub /goal_mode std_msgs/String "data: ''2''" &';
%             [statuss,cmdout] = system([pre cmd]);
        end

        % Button pushed function: DetectFoodButton
        function DetectFoodButtonPushed(app, event)
            if prod(abs(app.robotArm.getPos() - app.home_position ) < 0.1)
                app.robotArm.cartesian_pose_client([-0.2888; -0.1129; 0.5370], [0.6309; -0.3101; -0.2724; 0.6570], 0);
                update_image(app)
                app.robotArm.cartesian_pose_client([0.0718; 0.3232; 0.5098], [0.0077; -0.7184; -0.6828; 0.1325], 0);
                update_image(app)
            end
            if isempty(app.tag_pos)
                init_pos = [ 0.1418; 0.4378; 0.2991];
                init_ori = [0.0251; 0.7279; 0.6834; 0.0502];
                app.robotArm.cartesian_pose_client(init_pos, init_ori, 0);
                update_image(app)
                app.robotArm.closeFinger([1700.0; 1700.0; 1700.0])
                pause(1)
                tag_pose = app.peripherals.lookUptransforms();
                app.tag_pos = tag_pose.position;
                app.tag_ori = tag_pose.quaternion;
            end  
            % detection position
            pos1 = app.tag_pos+[-0.0; -0.20; 0.1];
            ori1 = -app.tag_ori;
            temp_ori = [ori1(4); ori1(1:3)];
            % do rotation, the frame is base. 
            R = axang2rotm([0 0 1 -0.03*pi]);
            result = R * quat2rotm(temp_ori');
            result = rotm2quat(result)';
            % ori: [x, y, z, w]
            result = [result(2:4); result(1)];
            app.robotArm.cartesian_pose_client(pos1, result, 0);
            update_image(app)
            % publish the bounding box and list of objects 
            objDetection(app, 4);
            
            
            app.DetectedObjectsListBox.Items = app.object_list;
        end

        % Button pushed function: 
        % MoveHighlightedObjectfromtoSideButton
        function MoveHighlightedObjectfromtoSideButtonPushed(app, event)
            % approach the object in x and z direction
            app.robotArm.closeFinger([1700.0; 1700.0; 1700.0])
            disp(app.tag_pos)
            pos2 = app.tag_pos+[-0.05; -0.20; 0.1]+[-0.07; -0.0; 0.08];
            app.robotArm.cartesian_pose_client(pos2, -app.tag_ori, 0);
        
            % get ori: [x, y, z, w]
            temp_ori = app.robotArm.getOri();
            % ori: [w, x, y, z]
            temp_ori = [temp_ori(4); temp_ori(1:3)];
            % do rotation, the frame is base. 
            R = axang2rotm([0 0 1 -0.04*pi]);
            result = R * quat2rotm(temp_ori');
            result = rotm2quat(result)';
            % ori: [x, y, z, w]
            result = [result(2:4); result(1)];
        
            pos3 = pos2+[0;0.23;0];
            ori3 = result;
            app.robotArm.cartesian_pose_client(pos3, ori3, 0);
            app.robotArm.closeFinger([5000.0; 5000.0; 5000.0])
            
            temp_pos = app.robotArm.getPos();
            % move the object sideway
            if temp_pos(1) < app.tag_pos(1) 
                temp_pos(1) = app.tag_pos(1) + 0.1;
            else
                temp_pos(1) = app.tag_pos(1) - 0.1;
            end
            app.robotArm.cartesian_pose_client(temp_pos, -app.tag_ori, 0);
            app.robotArm.closeFinger([1700.0; 1700.0; 1700.0])
        
            % move back the arm
            pos5 = app.robotArm.getPos() + [-0.; -0.15; 0.];
            pos5(1) = app.tag_pos(1);
            app.robotArm.cartesian_pose_client(pos5, -app.tag_ori, 0);
        end

        % Button pushed function: GrabHighlightedObjectButton
        function GrabHighlightedObjectButtonPushed(app, event)
            move_base(app, [-0.3;0], 5);
            app.robotArm.closeFinger([1700.0; 1700.0; 1700.0])
            %adjust the distance in x, z direction 
            pos6 = app.tag_pos+[-0.05; -0.20; 0.1]+[-0.07; -0.0; 0.08];
            ori6 = app.robotArm.getOri();
            app.robotArm.cartesian_pose_client(pos6, ori6, 0);
        
            pos7 = pos6+[0;0.3;0];
            ori7 = ori6;
            app.robotArm.cartesian_pose_client(pos7, ori7, 0);
            app.robotArm.closeFinger([5000.0; 5000.0; 5000.0])
            
            % go back to the default value
            pos8 = app.tag_pos+[-0.05; -0.20; 0.18];
            ori8 = app.robotArm.getOri();
            app.robotArm.cartesian_pose_client(pos8, ori8, 0);
            move_base(app, [0.3;0], 5);

        end

        % Button pushed function: DeliverObjectButton
        function DeliverObjectButtonPushed(app, event)
            app.robotArm.cartesian_pose_client([0.3544; -0.1650; 0.4537], [0.5043; 0.4755; 0.5481; 0.4682], 0);
            pause(1)
            app.robotArm.closeFinger([1700.0; 1700.0; 1700.0])
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            move_base(app, [0.;0.3], 2);            
        end

        % Button pushed function: BackwardButton
        function BackwardButtonPushed(app, event)
            move_base(app, [0; -0.3], 2);
        end

        % Button pushed function: RightButton
        function RightButtonPushed(app, event)
            move_base(app, [0.3;0], 2);
        end

        % Button pushed function: LeftButton
        function LeftButtonPushed(app, event)
            move_base(app, [-0.3;0], 2);
        end

        % Callback function
        function CWButtonPushed(app, event)
            rotate_base(app, 1, 2)
        end

        % Callback function
        function CCWRotButtonPushed(app, event)
            rotate_base(app, -1, 2)            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.msg_goal_mode.Data = '-1';
            send(app.pub_goal_mode,app.msg_goal_mode);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UI = uifigure('Visible', 'off');
            app.UI.Name = 'UI Figure';
            app.Image_map = uiimage(app.UI);
            app.Image_map.ImageSource = 'map.png';
            app.Image_cam_arm = uiimage(app.UI);
            app.ArmCameraImageLabel = uilabel(app.UI);
            app.ArmCameraImageLabel.Text = 'Arm Camera Image';
            app.MapViewGoalSelectionLabel = uilabel(app.UI);
            app.MapViewGoalSelectionLabel.Text = 'Map View Goal Selection';
            app.DetectedObjectsListBoxLabel = uilabel(app.UI);
            app.DetectedObjectsListBoxLabel.Text = 'Detected Objects';
            app.DetectedObjectsListBox = uilistbox(app.UI);
            app.DetectedObjectsListBox.Items = {};
            app.DetectedObjectsListBox.Value = {};
            app.ArmDefaultPositionButton = uibutton(app.UI, 'push');
            app.ArmDefaultPositionButton.ButtonPushedFcn = createCallbackFcn(app, @ArmDefaultPositionButtonPushed, true);
            app.ArmDefaultPositionButton.Text = 'Arm Default Position';
            app.KinectCameraImageLabel = uilabel(app.UI);
            app.KinectCameraImageLabel.Text = 'Kinect Camera Image';
            app.Image_cam_kinect = uiimage(app.UI);
            app.BringRobottoUserButton = uibutton(app.UI, 'push');
            app.BringRobottoUserButton.ButtonPushedFcn = createCallbackFcn(app, @BringRobottoUserButtonPushed, true);
            app.BringRobottoUserButton.Text = 'Bring Robot to User';
            app.SendRobottoFridgeButton = uibutton(app.UI, 'push');
            app.SendRobottoFridgeButton.ButtonPushedFcn = createCallbackFcn(app, @SendRobottoFridgeButtonPushed, true);
            app.SendRobottoFridgeButton.Text = 'Send Robot to Fridge';
            app.MoveHighlightedObjectfromtoSideButton = uibutton(app.UI, 'push');
            app.MoveHighlightedObjectfromtoSideButton.ButtonPushedFcn = createCallbackFcn(app, @MoveHighlightedObjectfromtoSideButtonPushed, true);
            app.MoveHighlightedObjectfromtoSideButton.Text = 'Move Highlighted Object from to Side';
            app.GrabHighlightedObjectButton = uibutton(app.UI, 'push');
            app.GrabHighlightedObjectButton.ButtonPushedFcn = createCallbackFcn(app, @GrabHighlightedObjectButtonPushed, true);
            app.GrabHighlightedObjectButton.Text = 'Grab Highlighted Object';
            app.SendRobottoMapSelectedGoalButton = uibutton(app.UI, 'push');
            app.SendRobottoMapSelectedGoalButton.Text = 'Send Robot to Map Selected Goal';
            app.DetectFoodButton = uibutton(app.UI, 'push');
            app.DetectFoodButton.ButtonPushedFcn = createCallbackFcn(app, @DetectFoodButtonPushed, true);
            app.DetectFoodButton.Text = 'Detect Food';
            app.DeliverObjectButton = uibutton(app.UI, 'push');
            app.DeliverObjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeliverObjectButtonPushed, true);
            app.DeliverObjectButton.Text = 'Deliver Object';
            app.ForwardButton = uibutton(app.UI, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.BackwardButton = uibutton(app.UI, 'push');
            app.BackwardButton.ButtonPushedFcn = createCallbackFcn(app, @BackwardButtonPushed, true);
            app.BackwardButton.Text = 'Backward';
            app.RightButton = uibutton(app.UI, 'push');
            app.RightButton.ButtonPushedFcn = createCallbackFcn(app, @RightButtonPushed, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.UI, 'push');
            app.LeftButton.ButtonPushedFcn = createCallbackFcn(app, @LeftButtonPushed, true);
            app.LeftButton.Text = 'Left';
            app.CCWButton = uibutton(app.UI, 'push');
            app.CCWButton.Text = 'CCW';
            app.CWButton = uibutton(app.UI, 'push');
            app.CWButton.Text = 'CW';
            app.STOPButton = uibutton(app.UI, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            % Show the figure after all components are created
            app.UI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UI)
        end
    end
end